classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataPanel                       matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        DatasTab                        matlab.ui.container.Tab
        DataGraphTab                    matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        UIAxes_delta_t                  matlab.ui.control.UIAxes
        UIAxes_delta_a                  matlab.ui.control.UIAxes
        UIAxes_delta_e                  matlab.ui.control.UIAxes
        UIAxes_delta_r                  matlab.ui.control.UIAxes
        UIAxes4_4                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes4_1                       matlab.ui.control.UIAxes
        UIAxes1_3                       matlab.ui.control.UIAxes
        UIAxes3_4                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_1                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes1_4                       matlab.ui.control.UIAxes
        UIAxes2_1                       matlab.ui.control.UIAxes
        UIAxes1_1                       matlab.ui.control.UIAxes
        UIAxes1_2                       matlab.ui.control.UIAxes
        DTrajectoryTab                  matlab.ui.container.Tab
        UIAxes_map                      matlab.ui.control.UIAxes
        HeadingHeadingIndicator         Aero.ui.control.HeadingIndicator
        HeadingHeadingIndicatorLabel    matlab.ui.control.Label
        AirspeedAirspeedIndicator       Aero.ui.control.AirspeedIndicator
        AirspeedAirspeedIndicatorLabel  matlab.ui.control.Label
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel          matlab.ui.control.Label
        Altimeter                       Aero.ui.control.Altimeter
        AltimeterLabel                  matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ManuelTab                       matlab.ui.container.Tab
        ThrustSlider                    matlab.ui.control.Slider
        ThrustSliderLabel               matlab.ui.control.Label
        RudderSlider                    matlab.ui.control.Slider
        RudderSliderLabel               matlab.ui.control.Label
        AileronSlider                   matlab.ui.control.Slider
        AileronSliderLabel              matlab.ui.control.Label
        ElevatorSlider                  matlab.ui.control.Slider
        ElevatorLabel                   matlab.ui.control.Label
        AutopilotTab                    matlab.ui.container.Tab
        CourseEditField                 matlab.ui.control.NumericEditField
        CourseEditFieldLabel            matlab.ui.control.Label
        AirspeedEditField               matlab.ui.control.NumericEditField
        AirspeedEditFieldLabel          matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        ONOFFLamp                       matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            format long;
            
            axisMatrix = [app.UIAxes1_1, app.UIAxes1_2, app.UIAxes1_3, app.UIAxes1_4;...
                    app.UIAxes2_1, app.UIAxes2_2, app.UIAxes2_3, app.UIAxes2_4;...
                    app.UIAxes3_1, app.UIAxes3_2, app.UIAxes3_3, app.UIAxes3_4;...
                    app.UIAxes4_1, app.UIAxes4_2, app.UIAxes4_3, app.UIAxes4_4;...
                    app.UIAxes_delta_e, app.UIAxes_delta_a, app.UIAxes_delta_r, app.UIAxes_delta_t;...
                    app.UIAxes_map, app.UIAxes_map, app.UIAxes_map, app.UIAxes_map];

            for i=1:21
                cla(axisMatrix(i));
            end

            cla(app.UIAxes);
            
            addpath('../tools/')
            run('../parameters/simulation_parameters')   % load SIM: simulation parameters
            run('../parameters/aerosonde_parameters')    % load MAV: aircraft parameters
            run('../parameters/wind_parameters')         % load WIND: wind parameters
            run('../parameters/sensor_parameters')       % load WIND: sensor parameters
            compute_autopilot_gains


            mav_view = spacecraft_viewer();              % Animation
            data_view = data_viewer();                   % Datas graph
            mav = mav_dynamics(SIM.ts_simulation, MAV);  % Dynamics of MAV
            gps = gps_sensor();                          % Gps

            sim_time = SIM.start_time;

            outData = [0; 0; 0; 0; 0; 0; 25; 0; 0; 0; 0; 0];
            delta = zeros(4,1);
            autopilot([zeros(22,1); 0; 0; 0; sim_time], AP);

            while sim_time < SIM.end_time
                
                other_sensor_values = sensors([mav.state; outData], MAV, SENSOR);           % Sensors
                gps_sensor_values = gps.update([outData(7:end); mav.state; sim_time], SENSOR, SIM.ts_simulation);       % Gps
                sensor_values = [other_sensor_values; gps_sensor_values; sim_time];
                filtered_sensor_values = estimate_states(sensor_values, mav.state, MAV, SENSOR, SIM);

                if app.Switch.Value == "On"
                    app.ONOFFLamp.Color = "green";
                    Va_c = app.AirspeedEditField.Value;
                    h_c = app.AltitudeEditField.Value;
                    chi_c = app.CourseEditField.Value/180*pi;

                    %realStates = [mav.state; outData(7:12); sim_time];
                    %convergedStates = true_states(realStates);
                    output_autopilot = autopilot([filtered_sensor_values; Va_c; h_c; chi_c; sim_time], AP);
                    delta = output_autopilot(1:4);

                    outData = forces_moments(mav.state, delta, WIND, MAV, sim_time);
                    
                    estimated_state = filtered_sensor_values;
                    commanded_state = [output_autopilot(5:end); delta];


                else
                    app.ONOFFLamp.Color = "red";
                    delta(1) = app.ElevatorSlider.Value*pi/180;
                    delta(2) = app.AileronSlider.Value*pi/180;
                    delta(3) = app.RudderSlider.Value*pi/180;
                    delta(4) = app.ThrustSlider.Value;

                    outData = forces_moments(mav.state, delta, WIND, MAV, sim_time);

                    estimated_state = filtered_sensor_values;
                    commanded_state = [zeros(12,1); delta];

                end
                

                data_view.update(mav.true_state,... % true states
                                estimated_state,... % estimated states
                                commanded_state,... % commmanded states
                                SIM.ts_simulation, axisMatrix); 

                                
                mav_view.update(mav.true_state, app.UIAxes);             % Draw


                mav.update_state(outData, MAV);                          % Mav dynamic


                app.SimulationTimeEditField.Value = sim_time;
                app.HeadingHeadingIndicator.Value = (mav.true_state.psi/pi)*180;
                app.ArtificialHorizon.Value = ([mav.true_state.phi, mav.true_state.theta]/pi)*180;
                app.Altimeter.Value = mav.true_state.h;
                app.AirspeedAirspeedIndicator.Value = mav.true_state.Va*1.94384449 ;
                
                sim_time = sim_time + SIM.ts_simulation;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'fx_edit';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.TabGroup = uitabgroup(app.ControlPanel);
            app.ManuelTab = uitab(app.TabGroup);
            app.ManuelTab.Tooltip = {'character vector'};
            app.ManuelTab.Title = 'Manuel';
            app.ElevatorLabel = uilabel(app.ManuelTab);
            app.ElevatorLabel.Text = 'Elevator';
            app.ElevatorSlider = uislider(app.ManuelTab);
            app.ElevatorSlider.Limits = [-20 20];
            app.ElevatorSlider.Tag = 'fx_edit';
            app.AileronSliderLabel = uilabel(app.ManuelTab);
            app.AileronSliderLabel.Text = 'Aileron';
            app.AileronSlider = uislider(app.ManuelTab);
            app.AileronSlider.Limits = [-20 20];
            app.AileronSlider.Tag = 'fy_edit';
            app.RudderSliderLabel = uilabel(app.ManuelTab);
            app.RudderSliderLabel.Text = 'Rudder';
            app.RudderSlider = uislider(app.ManuelTab);
            app.RudderSlider.Limits = [-20 20];
            app.RudderSlider.Tag = 'Mz_edit';
            app.ThrustSliderLabel = uilabel(app.ManuelTab);
            app.ThrustSliderLabel.Text = 'Thrust';
            app.ThrustSlider = uislider(app.ManuelTab);
            app.ThrustSlider.Limits = [0 1];
            app.ThrustSlider.Tag = 'Mx_edit';
            app.AutopilotTab = uitab(app.TabGroup);
            app.AutopilotTab.Title = 'Autopilot';
            app.ONOFFLamp = uilamp(app.AutopilotTab);
            app.Switch = uiswitch(app.AutopilotTab, 'toggle');
            app.AltitudeEditFieldLabel = uilabel(app.AutopilotTab);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.AutopilotTab, 'numeric');
            app.AirspeedEditFieldLabel = uilabel(app.AutopilotTab);
            app.AirspeedEditFieldLabel.Text = 'Airspeed';
            app.AirspeedEditField = uieditfield(app.AutopilotTab, 'numeric');
            app.CourseEditFieldLabel = uilabel(app.AutopilotTab);
            app.CourseEditFieldLabel.Text = 'Course';
            app.CourseEditField = uieditfield(app.AutopilotTab, 'numeric');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tag = 'start_button';
            app.STARTButton.Text = {'START'; ''};
            app.SimulationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltimeterLabel = uilabel(app.UIFigure);
            app.AltimeterLabel.Text = 'Altimeter';
            app.Altimeter = uiaeroaltimeter(app.UIFigure);
            app.ArtificialHorizonLabel = uilabel(app.UIFigure);
            app.ArtificialHorizonLabel.Text = 'Artificial Horizon';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.AirspeedAirspeedIndicatorLabel = uilabel(app.UIFigure);
            app.AirspeedAirspeedIndicatorLabel.Text = 'Airspeed';
            app.AirspeedAirspeedIndicator = uiaeroairspeed(app.UIFigure);
            app.HeadingHeadingIndicatorLabel = uilabel(app.UIFigure);
            app.HeadingHeadingIndicatorLabel.Text = 'Heading';
            app.HeadingHeadingIndicator = uiaeroheading(app.UIFigure);
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.TabGroup2 = uitabgroup(app.DataPanel);
            app.DatasTab = uitab(app.TabGroup2);
            app.DatasTab.Title = 'Datas';
            app.DataGraphTab = uitab(app.TabGroup2);
            app.DataGraphTab.Title = 'Data Graph';
            app.UIAxes1_2 = uiaxes(app.DataGraphTab);
            app.UIAxes1_1 = uiaxes(app.DataGraphTab);
            app.UIAxes1_1.Tag = 'UIAxes1_1';
            app.UIAxes2_1 = uiaxes(app.DataGraphTab);
            app.UIAxes1_4 = uiaxes(app.DataGraphTab);
            app.UIAxes2_4 = uiaxes(app.DataGraphTab);
            app.UIAxes3_1 = uiaxes(app.DataGraphTab);
            app.UIAxes3_3 = uiaxes(app.DataGraphTab);
            app.UIAxes3_4 = uiaxes(app.DataGraphTab);
            app.UIAxes1_3 = uiaxes(app.DataGraphTab);
            app.UIAxes4_1 = uiaxes(app.DataGraphTab);
            app.UIAxes2_3 = uiaxes(app.DataGraphTab);
            app.UIAxes4_2 = uiaxes(app.DataGraphTab);
            app.UIAxes3_2 = uiaxes(app.DataGraphTab);
            app.UIAxes2_2 = uiaxes(app.DataGraphTab);
            app.UIAxes4_3 = uiaxes(app.DataGraphTab);
            app.UIAxes4_4 = uiaxes(app.DataGraphTab);
            app.UIAxes4_4.Visible = 'off';
            app.UIAxes_delta_r = uiaxes(app.DataGraphTab);
            app.UIAxes_delta_e = uiaxes(app.DataGraphTab);
            app.UIAxes_delta_a = uiaxes(app.DataGraphTab);
            app.UIAxes_delta_t = uiaxes(app.DataGraphTab);
            app.Image = uiimage(app.DataGraphTab);
            app.Image.ImageSource = 'dotes.png';
            app.DTrajectoryTab = uitab(app.TabGroup2);
            app.DTrajectoryTab.Title = '3D Trajectory';
            app.UIAxes_map = uiaxes(app.DTrajectoryTab);
            app.UIAxes_map.Tag = 'UIAxes1_1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end